classdef LetsMakeaDeal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ModeDropDown        matlab.ui.control.DropDown
        StatusLabel         matlab.ui.control.Label
        SwitchButton        matlab.ui.control.Button
        StayButton          matlab.ui.control.Button
        DoorsPanel          matlab.ui.container.Panel
        LetsMakeaDealLabel  matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        prizeDoor
        chosenDoor
        openedDoors
        numDoors
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StatusLabel.Text = "Choose Door!";
            app.chosenDoor = [];
            app.openedDoors = [];


            selectedMode = app.ModeDropDown.Value;  % ganti 'mode' jadi 'selectedMode'
            switch selectedMode
                case 'Easy', app.numDoors = 3;
                case 'Medium', app.numDoors = 5;
                case 'Hard', app.numDoors = 7;
            end
            app.prizeDoor = randi(app.numDoors);

            delete(app.DoorsPanel.Children);
            
            % Update status
            app.StatusLabel.Text = sprintf('Mode %s with %d door. Please choose one door...', ... 
                selectedMode, app.numDoors);
            % ---- Layout Dinamis ----
            winWidth  = app.DoorsPanel.Position(3);   % lebar panel
            winHeight = app.DoorsPanel.Position(4);   % tinggi panel
            doorWidth = 80;
            doorHeight = 150;
            spacing = (winWidth - app.numDoors*doorWidth) / (app.numDoors+1);
            yPos = winHeight/2 - doorHeight/1.75; % posisi vertical ditengah panel

            % Buat tombol pintu sesuai mode
            for i = 1:app.numDoors
                xPos = spacing*i + doorWidth*(i-1);
                uibutton(app.DoorsPanel, 'Text', sprintf('Door %d', i), ...
                    'Position',[xPos yPos doorWidth doorHeight], ...
                    'ButtonPushedFcn', @(btn,event) app.DoorButtonPushed(i));
                btn.FontColor = [1 1 1]; % tulisan jadi putih biar kontras
            end
        end

        function DoorButtonPushed(app, doorNum)
            if isempty(app.chosenDoor)
                app.chosenDoor = doorNum;
                app.StatusLabel.Text = "Choose Door "+doorNum+". Open zonk...";
                pause(1);
                app.openHostDoor();
            else
                app.StatusLabel.Text = "Stay or Switch!";
            end
        end

        function openHostDoor(app)
            c = setdiff(1:app.numDoors,[app.chosenDoor, app.openedDoors, app.prizeDoor]);
            if ~isempty(c)
                openDoor = c(randi(numel(c)));
                app.openedDoors(end+1) = openDoor;
                btns = app.DoorsPanel.Children;
                btn = btns(app.numDoors - openDoor + 1);
                btn.Text = "ZONK!";
                btn.Enable = 'off';
                btn.BackgroundColor = [0.8 0.8 0.8];
            end
            app.StatusLabel.Text = "Stay or Switch?";
        end

        % Button pushed function: StayButton
        function StayButtonPushed(app, event)
            app.StatusLabel.Text = "STAY at door "+app.chosenDoor;
            app.nextRound();
        end

        % Button pushed function: SwitchButton
        function SwitchButtonPushed(app, event)
            app.StatusLabel.Text = "Klik other door!";
            app.chosenDoor = [];
        end

        function nextRound(app)
            remaining = setdiff(1:app.numDoors, app.openedDoors);
            if numel(remaining) > 2
                pause(1); app.openHostDoor();
            else
                btns = app.DoorsPanel.Children;
                for i=1:app.numDoors
                    btn = btns(app.numDoors - i + 1);
                    if i==app.prizeDoor
                        btn.Text = "PRIZE!";
                        btn.BackgroundColor = [0.5 1 0.5];
                    else
                        btn.Text = "ZONK!";
                        btn.BackgroundColor = [1 0.5 0.5];
                    end
                    btn.Enable = 'off';
                end
                if app.chosenDoor==app.prizeDoor
                    app.StatusLabel.Text="WIN!";
                else
                    app.StatusLabel.Text="LOSE, prize at "+app.prizeDoor+" door";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'bone');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LetsMakeaDealLabel = uilabel(app.UIFigure);
            app.LetsMakeaDealLabel.Text = 'Let''s Make a Deal';
            app.DoorsPanel = uipanel(app.UIFigure);
            app.DoorsPanel.Title = 'Doors';
            app.StayButton = uibutton(app.UIFigure, 'push');
            app.StayButton.ButtonPushedFcn = createCallbackFcn(app, @StayButtonPushed, true);
            app.StayButton.Text = 'Stay';
            app.SwitchButton = uibutton(app.UIFigure, 'push');
            app.SwitchButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchButtonPushed, true);
            app.SwitchButton.Text = 'Switch';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status ...';
            app.ModeDropDown = uidropdown(app.UIFigure);
            app.ModeDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.ModeDropDown.Value = 'Easy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LetsMakeaDeal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end